classdef home_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HelpMenu            matlab.ui.container.Menu
        Label               matlab.ui.control.Label
        RunSimulatorButton  matlab.ui.control.Button
        SettingButton       matlab.ui.control.Button
        OtherTasksButton    matlab.ui.control.Button
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        cam
        colDet
        capDet
        converter
        
        setting
        help
        other
    end
    
    methods (Access = public)
        
        function results = disableButtons(app)
            app.HelpMenu.Enable = false;
            app.SettingButton.Enable = false;
            app.RunSimulatorButton.Enable = false;
            app.OtherTasksButton.Enable = false;
            results = true;
            return
        end

        function results = enableButtons(app)
            app.HelpMenu.Enable = true;
            app.SettingButton.Enable = true;
            app.RunSimulatorButton.Enable = true;
            app.OtherTasksButton.Enable = true;
            results = true;
            return
        end

        function results = copyObjects(app, camera, capDet, colDet)
            app.cam = camera;
            app.capDet = capDet;
            app.colDet = colDet;

%             app.cam
%             app.capDet
%             app.colDet
            results = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cam = Camera();
            app.colDet = Colour_Detection();
            app.capDet = Cap_Detection();
            app.converter = Coordinate_Converter();
            img = app.cam.tempImageAcq(1,'l','3840x1080', 4, 4, 4, 4, 5);
            app.Image.ImageSource = img;
        end

        % Button pushed function: SettingButton
        function SettingButtonPushed(app, event)
            app.disableButtons();
            app.setting = setting_interface(app, app.cam, app.capDet, app.colDet);
        end

        % Button pushed function: OtherTasksButton
        function OtherTasksButtonPushed(app, event)
            app.disableButtons();
            app.other = other_task_interface(app);    
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.disableButtons();
            app.help = home_help_interface();
        end

        % Button pushed function: RunSimulatorButton
        function RunSimulatorButtonPushed(app, event)
            tcpConnection = Connection("192.168.0.20", 1025);
            tcpConnection.move2Cam();
            imD = app.cam.imageAcq(1, 'l');
            caps = app.capDet.detectCaps(imD);
            capL = app.colDet.detectColour(imD, caps(:,1:2), caps(:,3), app.capDet.dia);
            capL2 = app.colDet.eliminateDuplicate(capL, 100);
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            img = app.colDet.visualiseAnalysis(capL2, imN);
            app.Image.ImageSource = img;
            app.Label.Text = "There are " + num2str(height(capL2)) + "bottles detected. Sending coordnate data to the robot.";
            tcpConnection.move2Bot(capL2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.Image = uiimage(app.UIFigure);
            app.OtherTasksButton = uibutton(app.UIFigure, 'push');
            app.OtherTasksButton.ButtonPushedFcn = createCallbackFcn(app, @OtherTasksButtonPushed, true);
            app.OtherTasksButton.Text = 'Other Tasks';
            app.SettingButton = uibutton(app.UIFigure, 'push');
            app.SettingButton.ButtonPushedFcn = createCallbackFcn(app, @SettingButtonPushed, true);
            app.SettingButton.Text = 'Setting';
            app.RunSimulatorButton = uibutton(app.UIFigure, 'push');
            app.RunSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulatorButtonPushed, true);
            app.RunSimulatorButton.Text = 'Run Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Welcome to Palletizing simulator. Please go through the setting, then press "Run Simulator" to start the simulation!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end