classdef setting_help_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CloseButton         matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        CapDetectionTab     matlab.ui.container.Tab
        Label               matlab.ui.control.Label
        CapdiameterTab      matlab.ui.container.Tab
        Label_2             matlab.ui.control.Label
        ColourDetectuibTab  matlab.ui.container.Tab
        Label_3             matlab.ui.control.Label
        BoxDetectionTab     matlab.ui.container.Tab
        Label_4             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            txt = "Generally, the camera settings are optimised to detect the caps in different light conditions,";
            txt = txt + newline + "thus it is not recommended to change them. However, it should be changed when changing";
            txt = txt + newline + " the Sensitivity and Edge Threshhold does not help improve the detection.";
            txt = txt + newline + newline + "Brightness: general brightness of the image, the smaller the brightness value, the darker the image will be.";
            txt = txt + newline + newline + "Contrast: difference in tones of the image, the smaller the contrast value, the more monotrous the image will be.";
            txt = txt + newline + newline + "Saturation: the intensities of the colours in the image, the smaller the saturation, the less vivid the image will be";
            txt = txt + newline + newline + "Sharpness: sharpness of the edges in image, the smaller the sharpness value, the weaker the edges of the objects will be";

            txt = txt + newline + newline + newline + "Sensitivity: sensitivity of cap detection, the higher the sensitivity value, the more deformed the cap can be to be detected.";
            txt = txt + newline + newline + "Edge Threshold: threshold for an object to be detected, the lower the edge threshold, the more objects to be detected.";
            app.Label.Text = txt;

            txt = "Diameter of the caps (in pixels), this setting should not be changed unless the height when taking photo of the bottles";
            app.Label_2.Text = txt;

            txt = "The spinners on the left side controls the RGB boundaries for brown capped bottles, and right side is for the red capped bottles.";
            txt = txt + newline + "If the red cap R Upper Limit is 255 and R Lower Limit is 100, then it means that R value of a pixel must be between 100 to 255 to be considered red";
            app.Label_3.Text = txt;

            txt = "This tab is for the box detection.";
            txt = txt + newline + newline + "Brightness Threshold: cutoff value for what region to be displayed black or white.";
            txt = txt + newline + newline + "Edge Connecter: The higher this values is the more connected the edge of pixels are";
            txt = txt + newline + "Increasing this too much may cause misdetection.";
            txt = txt + newline + newline + "Vertical/Horizontal dist. to the edge: pixel distance between the edge of boxes and centre of boxes.";
            txt = txt + newline + "Note that box should be positioned in portrait orientation when dealing with this setting.";

            app.Label_4.Text = txt;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapDetectionTab = uitab(app.TabGroup);
            app.CapDetectionTab.Title = 'Cap Detection';
            app.Label = uilabel(app.CapDetectionTab);
            app.CapdiameterTab = uitab(app.TabGroup);
            app.CapdiameterTab.Title = 'Cap diameter';
            app.Label_2 = uilabel(app.CapdiameterTab);
            app.ColourDetectuibTab = uitab(app.TabGroup);
            app.ColourDetectuibTab.Title = 'Colour Detectuib';
            app.Label_3 = uilabel(app.ColourDetectuibTab);
            app.BoxDetectionTab = uitab(app.TabGroup);
            app.BoxDetectionTab.Title = 'Box Detection';
            app.Label_4 = uilabel(app.BoxDetectionTab);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setting_help_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end