classdef setting_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HelpMenu                     matlab.ui.container.Menu
        iconLabel                    matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        ApplyChangesButton           matlab.ui.control.Button
        RestoreDefaultButton         matlab.ui.control.Button
        readyIcon                    matlab.ui.control.Image
        previewImage                 matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        CapDetectionTab              matlab.ui.container.Tab
        SensitivitySettingPanel      matlab.ui.container.Panel
        EdgeThresholdSlider          matlab.ui.control.Slider
        EdgeThresholdSliderLabel     matlab.ui.control.Label
        SensitivitySlider            matlab.ui.control.Slider
        SensitivitySliderLabel       matlab.ui.control.Label
        CameraSettingPanel           matlab.ui.container.Panel
        GammaSlider                  matlab.ui.control.Slider
        GammaSliderLabel             matlab.ui.control.Label
        SaturationSlider             matlab.ui.control.Slider
        SaturationSliderLabel        matlab.ui.control.Label
        SharpnessSlider              matlab.ui.control.Slider
        SharpnessSliderLabel         matlab.ui.control.Label
        ContrastSlider               matlab.ui.control.Slider
        ContrastSliderLabel          matlab.ui.control.Label
        BrightnessSlider             matlab.ui.control.Slider
        BrightnessSliderLabel        matlab.ui.control.Label
        CapDiameterTab               matlab.ui.container.Tab
        ChangeButton                 matlab.ui.control.Button
        DiameterLabel                matlab.ui.control.Label
        ColourDetectionTab           matlab.ui.container.Tab
        RedCapRGBlimitsPanel         matlab.ui.container.Panel
        GLowerLimitEditField_2       matlab.ui.control.NumericEditField
        GLowerLimitEditField_2Label  matlab.ui.control.Label
        BLowerLimitEditField_2       matlab.ui.control.NumericEditField
        BLowerLimitEditField_2Label  matlab.ui.control.Label
        BUpperLimitEditField_2       matlab.ui.control.NumericEditField
        BUpperLimitEditField_2Label  matlab.ui.control.Label
        GUpperLimitEditField_2       matlab.ui.control.NumericEditField
        GUpperLimitEditField_2Label  matlab.ui.control.Label
        RLowerLimitEditField_2       matlab.ui.control.NumericEditField
        RLowerLimitEditField_2Label  matlab.ui.control.Label
        RUpperLimitEditField_2       matlab.ui.control.NumericEditField
        RUpperLimitEditField_2Label  matlab.ui.control.Label
        BrownCapRGBlimitsPanel       matlab.ui.container.Panel
        GLowerLimitEditField         matlab.ui.control.NumericEditField
        GLowerLimitEditFieldLabel    matlab.ui.control.Label
        BLowerLimitEditField         matlab.ui.control.NumericEditField
        BLowerLimitEditFieldLabel    matlab.ui.control.Label
        BUpperLimitEditField         matlab.ui.control.NumericEditField
        BUpperLimitEditFieldLabel    matlab.ui.control.Label
        GUpperLimitEditField         matlab.ui.control.NumericEditField
        GUpperLimitEditFieldLabel    matlab.ui.control.Label
        RLowerLimitEditField         matlab.ui.control.NumericEditField
        RLowerLimitEditFieldLabel    matlab.ui.control.Label
        RUpperLimitEditField         matlab.ui.control.NumericEditField
        RUpperLimitEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam
        colDet
        capDet
        
        home% Description
    end
    
    methods (Access = private)
        
        function results = disableSliders(app)
            app.BrightnessSlider.Enable = false;
            app.ContrastSlider.Enable = false;
            app.SaturationSlider.Enable = false;
            app.SharpnessSlider.Enable = false;
            app.GammaSlider.Enable = false;
            app.SensitivitySlider.Enable = false;
            app.EdgeThresholdSlider.Enable = false;

            app.ChangeButton.Enable = false;

            app.RLowerLimitEditField.Enable = false;
            app.RUpperLimitEditField.Enable = false;
            app.RLowerLimitEditField_2.Enable = false;
            app.RUpperLimitEditField_2.Enable =false;

            app.GLowerLimitEditField.Enable = false;
            app.GUpperLimitEditField_2.Enable = false;
            app.GLowerLimitEditField_2.Enable = false;
            app.GUpperLimitEditField.Enable = false;

            app.BLowerLimitEditField.Enable = false;
            app.BLowerLimitEditField_2.Enable = false;
            app.BUpperLimitEditField.Enable = false;
            app.BUpperLimitEditField_2.Enable = false;
            results = true;
            return
        end

        function results = enableSliders(app)
            app.BrightnessSlider.Enable = true;
            app.ContrastSlider.Enable = true;
            app.SaturationSlider.Enable = true;
            app.SharpnessSlider.Enable = true;
            app.GammaSlider.Enable = true;
            app.SensitivitySlider.Enable = true;
            app.EdgeThresholdSlider.Enable = true;

            app.ChangeButton.Enable = true;

            app.RLowerLimitEditField.Enable = true;
            app.RUpperLimitEditField.Enable = true;
            app.RLowerLimitEditField_2.Enable = true;
            app.RUpperLimitEditField_2.Enable =true;

            app.GLowerLimitEditField.Enable = true;
            app.GUpperLimitEditField_2.Enable = true;
            app.GLowerLimitEditField_2.Enable = true;
            app.GUpperLimitEditField.Enable = true;

            app.BLowerLimitEditField.Enable = true;
            app.BLowerLimitEditField_2.Enable = true;
            app.BUpperLimitEditField.Enable = true;
            app.BUpperLimitEditField_2.Enable = true;
            results = true;
            return
        end

        function changeIcon(app, capNum, mode)
            if mode == 1
                if capNum == 10 
                    txt = "There are 10 caps detected with current settings. "; 
                    txt = txt + newline + "Press Apply Changes then exit setting screen!";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Colour_ready.png";
                else
                    app.iconLabel.Text = "There are "+ num2str(capNum) + " caps detected with current settings.";
                end
            else
                if capNum > 10
                    txt = "There are "+ num2str(capNum) + " caps detected with current settings.";
                    txt = txt + newline + "Go to Colour Detection tab to chack the colour detection!";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_ready.png";
                else
                    txt = "There are "+ num2str(capNum) + " caps detected with current settings.";
                    txt = txt + newline + "Hint: increase Sensitivity or deEdge Threshold!";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_not_ready.png";
                end
            end
        end

        function caps = findCaps(app)
            app.disableSliders();
            imD = app.cam.tempImageAcq(1,'l', '3840x1080', app.BrightnessSlider.Value, app.ContrastSlider.Value, app.SaturationSlider.Value, app.SharpnessSlider.Value, app.GammaSlider.Value);
            caps = app.capDet.detectCaps(imD);
            app.enableSliders();
            return
        end

        function capList = findColours(app)
            app.disableSliders();
            imD = app.cam.tempImageAcq(1,'l', '3840x1080', app.BrightnessSlider.Value, app.ContrastSlider.Value, app.SaturationSlider.Value, app.SharpnessSlider.Value, app.GammaSlider.Value);
            caps = app.capDet.detectCaps(imD);
            capList = app.colDet.eliminateDuplicate(app.colDet.detectColour(imD, caps(:,1:2), caps(:,3), app.capDet.dia), 100);
            app.enableSliders();
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, camera, cap_detector, colour_detector)
            app.home = callingApp;
            app.cam = camera;
            app.colDet = colour_detector;
            app.capDet = cap_detector;
            app.cam
            txt = "Current cap diameter is " + num2str(app.capDet.dia) +"  pixels (Average). Would you like to change it?";
            txt = txt+ newline + "(This setting should only be changed when camera position height is changed)";
            app.DiameterLabel.Text = txt;

            app.BrightnessSlider.Value = app.cam.bright;
            app.ContrastSlider.Value = app.cam.cont;
            app.SaturationSlider.Value = app.cam.sat;
            app.SharpnessSlider.Value = app.cam.sharp;
            app.GammaSlider.Value = app.cam.gam;

            app.SensitivitySlider.Value = app.capDet.sens;
            app.EdgeThresholdSlider.Value = app.capDet.edge;

            app.RLowerLimitEditField.Value = app.colDet.RB(1);
            app.RUpperLimitEditField.Value = app.colDet.RB(2);
            app.RLowerLimitEditField_2.Value = app.colDet.RR(1);
            app.RUpperLimitEditField_2.Value = app.colDet.RR(2);

            app.GLowerLimitEditField.Value = app.colDet.GB(1);
            app.GUpperLimitEditField.Value = app.colDet.GB(2);
            app.GLowerLimitEditField_2.Value = app.colDet.GR(1);
            app.GUpperLimitEditField_2.Value = app.colDet.GR(2);

            app.BLowerLimitEditField.Value = app.colDet.BB(1);
            app.BUpperLimitEditField.Value = app.colDet.BB(2);
            app.BLowerLimitEditField_2.Value = app.colDet.BR(1);
            app.BUpperLimitEditField_2.Value = app.colDet.BR(2);
            
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                enableButtons(app.home);
                delete(app);
            catch
                delete(app);
            end
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                enableButtons(app.home);
                delete(app);
            catch
                delete(app);
            end
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            if app.GammaSlider.Value == 9
                app.cam = app.cam.changeSettings('3840x1080', app.BrightnessSlider.Value, app.ContrastSlider.Value, app.SaturationSlider.Value, app.SharpnessSlider.Value, 500);
            else
                app.cam = app.cam.changeSettings('3840x1080', app.BrightnessSlider.Value, app.ContrastSlider.Value, app.SaturationSlider.Value, app.SharpnessSlider.Value, app.GammaSlider.Value);
            end
            app.capDet = app.capDet.setVariables(app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value);

            rb = [app.RLowerLimitEditField.Value, app.RUpperLimitEditField.Value];
            gb = [app.GLowerLimitEditField.Value, app.GUpperLimitEditField.Value];
            bb = [app.BLowerLimitEditField.Value, app.BUpperLimitEditField.Value];
            rr = [app.RLowerLimitEditField_2.Value, app.RUpperLimitEditField_2.Value];
            gr = [app.GLowerLimitEditField_2.Value, app.GUpperLimitEditField_2.Value];
            br = [app.BLowerLimitEditField_2.Value, app.BUpperLimitEditField_2.Value];
            if (rb(1) > rb(2)) || (gb(1) > gb(2)) || (bb(1) > bb(2)) || (rr(1) > rr(2)) || (gr(1) > gr(2)) || (br(1) > br(2))
                f = warndlg("lower limits cannot be greater than the upper limits");
                return 
            else
                app.colDet = app.colDet.setBoundaries(rb, gb, bb, rr, gr, br);
            end
            copyObjects(app.home, app.cam, app.capDet, app.colDet);
%             app.cam
%             app.capDet
%             app.colDet
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(width(capL), 2);
        end

        % Value changed function: EdgeThresholdSlider
        function EdgeThresholdSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps); 
            app.changeIcon(width(capL), 2);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            %Add code later
        end

        % Value changed function: RUpperLimitEditField
        function RUpperLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RLowerLimitEditField
        function RLowerLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GUpperLimitEditField
        function GUpperLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GLowerLimitEditField
        function GLowerLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BUpperLimitEditField
        function BUpperLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BLowerLimitEditField
        function BLowerLimitEditFieldValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RUpperLimitEditField_2
        function RUpperLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RLowerLimitEditField_2
        function RLowerLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GUpperLimitEditField_2
        function GUpperLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GLowerLimitEditField_2
        function GLowerLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BUpperLimitEditField_2
        function BUpperLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BLowerLimitEditField_2
        function BLowerLimitEditField_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapDetectionTab = uitab(app.TabGroup);
            app.CapDetectionTab.Title = 'Cap Detection';
            app.CameraSettingPanel = uipanel(app.CapDetectionTab);
            app.CameraSettingPanel.Title = 'Camera Setting';
            app.BrightnessSliderLabel = uilabel(app.CameraSettingPanel);
            app.BrightnessSliderLabel.Text = {'Brightness'; ''; ''};
            app.BrightnessSlider = uislider(app.CameraSettingPanel);
            app.BrightnessSlider.Limits = [0 8];
            app.BrightnessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.CameraSettingPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraSettingPanel);
            app.ContrastSlider.Limits = [0 8];
            app.ContrastSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.SharpnessSliderLabel = uilabel(app.CameraSettingPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraSettingPanel);
            app.SharpnessSlider.Limits = [0 8];
            app.SharpnessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanged, true);
            app.SaturationSliderLabel = uilabel(app.CameraSettingPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraSettingPanel);
            app.SaturationSlider.Limits = [0 8];
            app.SaturationSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.GammaSliderLabel = uilabel(app.CameraSettingPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.CameraSettingPanel);
            app.GammaSlider.Limits = [1 9];
            app.GammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.Value = 1;
            app.SensitivitySettingPanel = uipanel(app.CapDetectionTab);
            app.SensitivitySettingPanel.Title = 'Sensitivity Setting';
            app.SensitivitySliderLabel = uilabel(app.SensitivitySettingPanel);
            app.SensitivitySliderLabel.Text = {'Sensitivity'; ''};
            app.SensitivitySlider = uislider(app.SensitivitySettingPanel);
            app.SensitivitySlider.Limits = [0.9 1];
            app.SensitivitySlider.MajorTicks = [0.9 0.92 0.94 0.96 0.98 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 0.9;
            app.EdgeThresholdSliderLabel = uilabel(app.SensitivitySettingPanel);
            app.EdgeThresholdSliderLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.SensitivitySettingPanel);
            app.EdgeThresholdSlider.Limits = [0 0.05];
            app.EdgeThresholdSlider.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05];
            app.EdgeThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeThresholdSliderValueChanged, true);
            app.CapDiameterTab = uitab(app.TabGroup);
            app.CapDiameterTab.Title = 'Cap Diameter';
            app.DiameterLabel = uilabel(app.CapDiameterTab);
            app.DiameterLabel.Text = '';
            app.ChangeButton = uibutton(app.CapDiameterTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.ColourDetectionTab = uitab(app.TabGroup);
            app.ColourDetectionTab.Title = 'Colour Detection';
            app.BrownCapRGBlimitsPanel = uipanel(app.ColourDetectionTab);
            app.BrownCapRGBlimitsPanel.Title = 'Brown Cap RGB limits';
            app.RUpperLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.RUpperLimitEditFieldLabel.Text = 'R Upper Limit';
            app.RUpperLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.RUpperLimitEditField.Limits = [0 255];
            app.RUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @RUpperLimitEditFieldValueChanged, true);
            app.RLowerLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.RLowerLimitEditFieldLabel.Text = 'R Lower Limit';
            app.RLowerLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.RLowerLimitEditField.Limits = [0 255];
            app.RLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @RLowerLimitEditFieldValueChanged, true);
            app.GUpperLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.GUpperLimitEditFieldLabel.Text = 'G Upper Limit';
            app.GUpperLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.GUpperLimitEditField.Limits = [0 255];
            app.GUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @GUpperLimitEditFieldValueChanged, true);
            app.BUpperLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.BUpperLimitEditFieldLabel.Text = 'B Upper Limit';
            app.BUpperLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.BUpperLimitEditField.Limits = [0 255];
            app.BUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @BUpperLimitEditFieldValueChanged, true);
            app.BLowerLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.BLowerLimitEditFieldLabel.Text = 'B Lower Limit';
            app.BLowerLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.BLowerLimitEditField.Limits = [0 255];
            app.BLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @BLowerLimitEditFieldValueChanged, true);
            app.GLowerLimitEditFieldLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.GLowerLimitEditFieldLabel.Text = 'G Lower Limit';
            app.GLowerLimitEditField = uieditfield(app.BrownCapRGBlimitsPanel, 'numeric');
            app.GLowerLimitEditField.Limits = [0 255];
            app.GLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @GLowerLimitEditFieldValueChanged, true);
            app.RedCapRGBlimitsPanel = uipanel(app.ColourDetectionTab);
            app.RedCapRGBlimitsPanel.Title = 'Red Cap RGB limits';
            app.RUpperLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.RUpperLimitEditField_2Label.Text = 'R Upper Limit';
            app.RUpperLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.RUpperLimitEditField_2.Limits = [0 255];
            app.RUpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @RUpperLimitEditField_2ValueChanged, true);
            app.RLowerLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.RLowerLimitEditField_2Label.Text = 'R Lower Limit';
            app.RLowerLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.RLowerLimitEditField_2.Limits = [0 255];
            app.RLowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @RLowerLimitEditField_2ValueChanged, true);
            app.GUpperLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.GUpperLimitEditField_2Label.Text = 'G Upper Limit';
            app.GUpperLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.GUpperLimitEditField_2.Limits = [0 255];
            app.GUpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @GUpperLimitEditField_2ValueChanged, true);
            app.BUpperLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.BUpperLimitEditField_2Label.Text = 'B Upper Limit';
            app.BUpperLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.BUpperLimitEditField_2.Limits = [0 255];
            app.BUpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @BUpperLimitEditField_2ValueChanged, true);
            app.BLowerLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.BLowerLimitEditField_2Label.Text = 'B Lower Limit';
            app.BLowerLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.BLowerLimitEditField_2.Limits = [0 255];
            app.BLowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @BLowerLimitEditField_2ValueChanged, true);
            app.GLowerLimitEditField_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.GLowerLimitEditField_2Label.Text = 'G Lower Limit';
            app.GLowerLimitEditField_2 = uieditfield(app.RedCapRGBlimitsPanel, 'numeric');
            app.GLowerLimitEditField_2.Limits = [0 255];
            app.GLowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @GLowerLimitEditField_2ValueChanged, true);
            app.previewImage = uiimage(app.UIFigure);
            app.readyIcon = uiimage(app.UIFigure);
            app.readyIcon.ImageSource = 'Colours_ready.png';
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.iconLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setting_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end