classdef writing_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        HelpMenu    matlab.ui.container.Menu
        Label       matlab.ui.control.Label
        DoneButton  matlab.ui.control.Button
        Space       matlab.ui.control.EditField
        WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = separateString(app, str)
            str = split(str);
            letterCount = 0;
            newStr = [];
            newStrIndex = 1;
            startingIndex = 1;
            for w = 1:width(str)
                letterCount = letterCount + strlength(str);
                if letterCount > 7
                    for a = startingIndex:w
                        newStr(newStrIndex) = newStr(newStrIndex) + str(a);
                    end
                    newStrIndex = newStrIndex + 1;
                    letterCount = 0;
                    startingIndex = w;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text = "Welcome, press done to send information to the robot.";
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.DoneButton.Enable = false;
            app.Space.Enable = false;
            str = app.Space.Value;
            alph = isletter(str);
            space = isspace(str);
            a = alph(:) == space(:);
            if any(a == true)
                warndlg("The input must not contain special character.")
                app.DoneButton.Enable = true;
                app.Space.Enable = true;
                return
            end

            str2 = upper(str)
            try
                IP = "192.168.0.20";
                port = 1025;
                app.Label.Text = "Connecting to the robot server. Please wait.";
                tcp = tcpclient(IP, port);
                str = "Connected";
                tcp.write(str);
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                app.Label.Text = "Connected to server.";
                tcp.write("5");
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "InFive"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                txt = "Sending " + str2;
                app.Label.Text = txt;
                tcp.write(str2);
                flag = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "WriteFinished"
                        break
                    end
                    pause(0.5);
                end

            catch ME
                if ME.identifier == "connectionError"
                    warndlg("Cannot receive signal from server");
                else
                    warndlg("Cannot connect to server");
                end
            end
            clear tcp
            app.DoneButton.Enable = true;
            app.Space.Enable = true;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app)
            catch
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel = uilabel(app.UIFigure);
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel.Text = 'Write down what you want the robot to write on the board';
            app.Space = uieditfield(app.UIFigure, 'text');
            app.Space.Value = 'Sample';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = writing_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end